classdef EEC201_proj_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChooseAudioFileButton        matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        SelectedAudioEditFieldLabel  matlab.ui.control.Label
        SelectedAudioEditField       matlab.ui.control.EditField
        PlayoriginalaudiofileButton  matlab.ui.control.Button
        StartRecordingButton         matlab.ui.control.Button
        PlayRecordingButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        FileName % Imported Audio
        Fs % Imported Sample Rate
        x % song after audio read. used for sound().
        recordedVoice
        y
        audioObj
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function ImportWAVfileEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ChooseAudioFileButton
        function ChooseAudioFileButtonPushed(app, event)
    [app.FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
    [app.x, app.Fs] = audioread([PathName , app.FileName]);
    
    
    % displaying song title
    answer = app.FileName;
    app.SelectedAudioEditField.Value = answer;
    
    % changing samples to seconds
    Ts = 1/app.Fs;
    n = 0:length(app.x)-1;
    seconds = Ts*n;
    plot(app.UIAxes,seconds,app.x)
    %xticks(app.UIAxes,[0 
    
    % spectrogram
    window=kaiser(1200,5.48);
    nooverlap=1000;
    nfft=1024;
    [S,F,T] = spectrogram(app.x,window,nooverlap,nfft,app.Fs,'yaxis');
    surf(app.UIAxes2,T, F, 10*log10(abs(S)),'edgecolor','none'); 
    %view(2);
    
    %imagesc(app.UIAxes2, T, F, log(1+abs(S)) ); %plot the log spectrum
    %axis xy; axis tight; colormap(jet); view(0,0);
    
        end

        % Button pushed function: PlayoriginalaudiofileButton
        function PlayoriginalaudiofileButtonPushed(app, event)
            sound(app.x,app.Fs)
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
    
    app.audioObj = audiorecorder(44100,16,2);
    
    msgbox('Recording')
    
    recordblocking(app.audioObj,3.5);
    
    msgbox('Recording stopped')
    
%    assignin('base','audioObj',audioObj)
 
    app.y = getaudiodata(app.audioObj);
%    assignin('base','y',y)
    n = 0:length(app.y)-1;
    plot(app.UIAxes,n,app.y)


            % %             recorder = audiorecorder;
% %   %          record(recorder)
% %   %          app.recordedVoice = getaudiodata(recorder);
% %             recordblocking(recorder, 5);
% %             play(recorder)
%         global recObj
%         recObj = audiarecorder ; %create object
%         record(recObj); %start Recording

%         myVoice = audiorecorder;
% 
% % Define callbacks to show when
% % recording starts and completes.
%         myVoice.StartFcn = 'disp(''Start speaking.'')';
%         myVoice.StopFcn = 'disp(''End of recording.'')';
% 
%         record(myVoice, 5);
% %To listen to the recording, call the play method:
% 
%         play(myVoice);
        end

        % Callback function
        function PlotTimeDomainButtonPushed(app, event)
%         stop(recorder)
        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)

 %           y = evalin('base','y');
           
            
            sound(app.y,44100,16)

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChooseAudioFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAudioFileButtonPushed, true);
            app.ChooseAudioFileButton.Text = 'Choose Audio File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SelectedAudioEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedAudioEditFieldLabel.Text = 'Selected Audio';
            app.SelectedAudioEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedAudioEditField.Editable = 'off';
            app.PlayoriginalaudiofileButton = uibutton(app.UIFigure, 'push');
            app.PlayoriginalaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalaudiofileButtonPushed, true);
            app.PlayoriginalaudiofileButton.Text = 'Play original audio file';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PlayRecordingButton = uibutton(app.UIFigure, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Text = 'Play Recording';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEC201_proj_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end