classdef EEC201_proj_APP_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        RecordAudioPanel             matlab.ui.container.Panel
        StartRecordingButton         matlab.ui.control.Button
        PlayRecordingButton          matlab.ui.control.Button
        PlotTimeResponseButton       matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        ImportAudiofromDiskPanel     matlab.ui.container.Panel
        ImportAudioFileButton        matlab.ui.control.Button
        SelectedAudioEditFieldLabel  matlab.ui.control.Label
        SelectedAudioEditField       matlab.ui.control.EditField
        PlayoriginalaudiofileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        FileName % Imported Audio
        Fs % Imported Sample Rate
        x % song after audio read. used for sound().
        recordedVoice
        y
        audioObj
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Lamp.Enable = 'off';
           app.Lamp.Color = 'red';
           app.Lamp_2.Enable = 'off';
           app.Lamp_2.Color = 'green';
        end

        % Callback function
        function ImportWAVfileEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ImportAudioFileButton
        function ImportAudioFileButtonPushed(app, event)
    
    [app.FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
    [app.x, app.Fs] = audioread([PathName , app.FileName]);
    
    
    % displaying song title
    answer = app.FileName;
    app.SelectedAudioEditField.Value = answer;
    
    % changing samples to seconds
    Ts = 1/app.Fs;
    n = 0:length(app.x)-1;
    seconds = Ts*n;
    plot(app.UIAxes,seconds,app.x)
    %xticks(app.UIAxes,[0 
    
    % spectrogram
    window=kaiser(1200,5.48);
    nooverlap=1000;
    nfft=1024;
    [S,F,T] = spectrogram(app.x,window,nooverlap,nfft,app.Fs,'yaxis');
    
    imagesc(app.UIAxes2, F, T, log(abs(S'))); %plot the log spectrum
    set(app.UIAxes2,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom 
    axis square 
    axis tight
    %colorbar(ax)
 
    
    %surf(app.UIAxes2,T, F, 10*log10(abs(S)),'edgecolor','none'); 
    %view(2);
    
    %imagesc(app.UIAxes2, T, F, log(1+abs(S)) ); %plot the log spectrum
    %axis xy; axis tight; colormap(jet); view(0,0);
    
        end

        % Button pushed function: PlayoriginalaudiofileButton
        function PlayoriginalaudiofileButtonPushed(app, event)
            sound(app.x,app.Fs)
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
    
    app.audioObj = audiorecorder;   % recording object
    app.Lamp.Enable = 'on';
%    app.Lamp.Color = 'green';
    recordblocking(app.audioObj,5); % record block 5 seconds
    app.Lamp.Enable = 'off';
%    app.Lamp.Color = 'red';
    app.y = getaudiodata(app.audioObj); % save audio as vector
    app.y = app.y(100:length(app.y)-1); % throw away the pop at the beginning
    

        end

        % Callback function
        function PlotTimeDomainButtonPushed(app, event)
            
        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)

            app.Lamp_2.Enable = 'on';
            sound(app.y,8000,8)
            pause(6)
            app.Lamp_2.Enable = 'off';
            
        end

        % Button pushed function: PlotTimeResponseButton
        function PlotTimeResponseButtonPushed(app, event)
            

            n = 0:length(app.y)-1;      % samples of recorded audio            
            plot(app.UIAxes,n,app.y)    % plot recorded audio

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RecordAudioPanel = uipanel(app.UIFigure);
            app.RecordAudioPanel.Title = 'Record Audio';
            app.StartRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PlayRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Text = 'Play Recording';
            app.PlotTimeResponseButton = uibutton(app.RecordAudioPanel, 'push');
            app.PlotTimeResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTimeResponseButtonPushed, true);
            app.PlotTimeResponseButton.Text = 'Plot Time Response';
            app.Lamp = uilamp(app.RecordAudioPanel);
            app.Lamp_2 = uilamp(app.RecordAudioPanel);
            app.ImportAudiofromDiskPanel = uipanel(app.UIFigure);
            app.ImportAudiofromDiskPanel.Title = 'Import Audio from Disk';
            app.ImportAudioFileButton = uibutton(app.ImportAudiofromDiskPanel, 'push');
            app.ImportAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioFileButtonPushed, true);
            app.ImportAudioFileButton.Text = 'Import Audio File';
            app.SelectedAudioEditFieldLabel = uilabel(app.ImportAudiofromDiskPanel);
            app.SelectedAudioEditFieldLabel.Text = 'Selected Audio';
            app.SelectedAudioEditField = uieditfield(app.ImportAudiofromDiskPanel, 'text');
            app.SelectedAudioEditField.Editable = 'off';
            app.PlayoriginalaudiofileButton = uibutton(app.ImportAudiofromDiskPanel, 'push');
            app.PlayoriginalaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalaudiofileButtonPushed, true);
            app.PlayoriginalaudiofileButton.Text = 'Play original audio file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEC201_proj_APP_v3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end