classdef EEC201_proj_APP_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        RecordAudioPanel             matlab.ui.container.Panel
        StartRecordingButton         matlab.ui.control.Button
        PlayRecordingButton          matlab.ui.control.Button
        PlotTimeResponseSpectrogramButton  matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        SecondsDropDownLabel         matlab.ui.control.Label
        SecondsDropDown              matlab.ui.control.DropDown
        SampleRateDropDownLabel      matlab.ui.control.Label
        SampleRateDropDown           matlab.ui.control.DropDown
        ImportAudiofromDiskPanel     matlab.ui.container.Panel
        ImportAudioFileButton        matlab.ui.control.Button
        SelectedAudioEditFieldLabel  matlab.ui.control.Label
        SelectedAudioEditField       matlab.ui.control.EditField
        PlayoriginalaudiofileButton  matlab.ui.control.Button
        SynthesizeVoicePanel         matlab.ui.container.Panel
        SyntesizeVoiceButton         matlab.ui.control.Button
        PlaysynthesizedvoiceButton   matlab.ui.control.Button
        PlayoriginalvoiceButton      matlab.ui.control.Button
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_4                       matlab.ui.control.Lamp
        TextArea                     matlab.ui.control.TextArea
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        LPC10VOCODERLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileName % Imported Audio
        Fs % Imported Sample Rate
        x % song after audio read. used for sound().
        recordedVoice
        y
        audioObj
        synth_speech
        recordLength
        sampleRate
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           app.Lamp.Enable = 'off';
           app.Lamp.Color = 'red';
           app.Lamp_2.Enable = 'off';
           app.Lamp_2.Color = 'green';
           app.Lamp_3.Enable = 'off';
           app.Lamp_3.Color = 'yellow';
           app.Lamp_4.Enable = 'off';
           app.Lamp_4.Color = 'green';
           value1 = app.SampleRateDropDown.Value;
           app.sampleRate = str2double(value1);
           value2 = app.SecondsDropDown.Value;
           app.recordLength = str2double(value2);
           app.TextArea.Visible = 'off';
           
        end

        % Callback function
        function ImportWAVfileEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ImportAudioFileButton
        function ImportAudioFileButtonPushed(app, event)
    

    [app.FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
%    app.UIFigure.Visible = 'off';
    [app.y, app.Fs] = audioread([PathName , app.FileName]);
    
    
    % displaying song title
    answer = app.FileName;
    app.SelectedAudioEditField.Value = answer;
 
    % spectrogram
    window=kaiser(1200,5.48);
    nooverlap=1000;
    nfft=1024;

    [~,F,T,ps]=spectrogram(app.y,window,nooverlap,nfft,app.Fs,'yaxis');
    
    surf(app.UIAxes2,T, F, log10(abs(ps)),'edgecolor','none'); 
    app.UIFigure.Visible = 'on';
    view(app.UIAxes2,2);
    ylim(app.UIAxes2,[0 app.Fs/2])
    xlabel(app.UIAxes2,'Time [s]')
    ylabel(app.UIAxes2,'Frequency [Hz]')
    c=colorbar;
    c.Label.String='Power [dB]';
    close all
   
    % plot time response
    % changing samples to seconds
    Ts = 1/app.Fs;
    n = 0:length(app.y)-1;
    seconds = Ts*n;
    plot(app.UIAxes,seconds,app.y)


        end

        % Button pushed function: PlayoriginalaudiofileButton
        function PlayoriginalaudiofileButtonPushed(app, event)
            sound(app.y,app.Fs)
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
    
            
    % recording button        
    app.audioObj = audiorecorder(app.sampleRate,16,1);   % recording object
    app.Lamp.Enable = 'on';
    recordblocking(app.audioObj,app.recordLength); % record block 5 seconds
    app.Lamp.Enable = 'off';
    app.y = getaudiodata(app.audioObj); % save audio as vector
    app.y = app.y(100:length(app.y)-1); % throw away the pop at the beginning
    app.Fs = app.sampleRate;
    

        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)
            
            % play button (Record Audio Section)
            
            app.Lamp_2.Enable = 'on';
            sound(app.y,app.sampleRate,16)
            pause(app.recordLength)
            app.Lamp_2.Enable = 'off';
            
        end

        % Button pushed function: PlotTimeResponseSpectrogramButton
        function PlotTimeResponseSpectrogramButtonPushed(app, event)
            
            % plot spectrogram (Record Audio Section)
    
            window=kaiser(1200,5.48);
            overlap=1000;
            nfft=1024;
            [~,F,T,ps]=spectrogram(app.y,window,overlap,nfft,app.Fs,'yaxis');
    
            surf(app.UIAxes2,T, F, log10(abs(ps)),'edgecolor','none');
            app.UIFigure.Visible = 'on';
            view(app.UIAxes2,2);
            %ylim(app.UIAxes2,[0 app.Fs/2])
            xlabel(app.UIAxes2,'Time [s]')
            ylabel(app.UIAxes2,'Frequency [Hz]')
            c=colorbar;
            c.Label.String='Power [dB]';
            close all
            
             % Plot time response
            n = 0:length(app.y)-1;      % samples of recorded audio                     
            plot(app.UIAxes,n,app.y)    % plot recorded audio


        end

        % Button pushed function: SyntesizeVoiceButton
        function SyntesizeVoiceButtonPushed(app, event)
 
            
            % Synthesizes voice
            app.TextArea.Visible = 'on';
            app.TextArea.Value = {'...processing'};
            
%             t=length(app.y)./app.fs;
            app.Lamp_3.Enable = 'on';
            pause(1)
            window=kaiser(1200,5.48);
            M=10;  %order of LPC
            [aCoeff, pitch_plot, voiced, gain] = f_ENCODER(app.y, app.Fs, M);  %pitch_plot is pitch periods
            app.synth_speech = f_DECODER (aCoeff, pitch_plot, voiced, gain);

            window=kaiser(1200,5.48);
            nooverlap=1000;
            nfft=1024;
            pause(1)
            [~,F,T,ps]=spectrogram(app.synth_speech,window,nooverlap,nfft,app.Fs,'yaxis');

    
            surf(app.UIAxes4,T, F, log10(abs(ps)),'edgecolor','none'); 
            app.UIFigure.Visible = 'on';
            view(app.UIAxes4,2);
            ylim(app.UIAxes4,[0 app.Fs/2])
            xlabel(app.UIAxes4,'Time [s]')
            ylabel(app.UIAxes4,'Frequency [Hz]')
            c=colorbar;
            c.Label.String='Power [dB]';
            close all
            app.Lamp_3.Enable = 'off';
            
            
            Ts = 1/app.Fs;
            n = 0:length(app.synth_speech)-1;
            seconds2 = Ts*n;            
            plot(app.UIAxes3,seconds2,app.synth_speech); 
            title(app.UIAxes3,'Synthesised signal');
            
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {''};
        

        end

        % Button pushed function: PlaysynthesizedvoiceButton
        function PlaysynthesizedvoiceButtonPushed(app, event)
            
            % play synthesizer button (Synthesizer Section)
            
            app.Lamp_4.Enable = 'on';
            sound(app.synth_speech,app.Fs)
            pause(3)
            app.Lamp_4.Enable = 'off';
            
        end

        % Button pushed function: PlayoriginalvoiceButton
        function PlayoriginalvoiceButtonPushed(app, event)
            
            % play original button  (Synthesizer Section)
            app.Lamp_4.Enable = 'on';
            sound(app.y,app.Fs)
            pause(3)
            app.Lamp_4.Enable = 'off';
            
        end

        % Value changed function: SecondsDropDown
        function SecondsDropDownValueChanged(app, event)
           
            % Set recording length 
            
            value1 = app.SecondsDropDown.Value;
            app.recordLength = str2double(value1);
            
        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)
           
            % Set sample rate
            value2 = app.SampleRateDropDown.Value;
            app.sampleRate = str2double(value2);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Spectrogram')
            xlabel(app.UIAxes2, 'time [s]')
            ylabel(app.UIAxes2, 'frequency [Hz]')
            app.RecordAudioPanel = uipanel(app.UIFigure);
            app.RecordAudioPanel.Title = 'Record Audio';
            app.StartRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PlayRecordingButton = uibutton(app.RecordAudioPanel, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Text = 'Play Recording';
            app.PlotTimeResponseSpectrogramButton = uibutton(app.RecordAudioPanel, 'push');
            app.PlotTimeResponseSpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTimeResponseSpectrogramButtonPushed, true);
            app.PlotTimeResponseSpectrogramButton.Text = 'Plot Time Response & Spectrogram';
            app.Lamp = uilamp(app.RecordAudioPanel);
            app.Lamp_2 = uilamp(app.RecordAudioPanel);
            app.SecondsDropDownLabel = uilabel(app.RecordAudioPanel);
            app.SecondsDropDownLabel.Text = 'Seconds';
            app.SecondsDropDown = uidropdown(app.RecordAudioPanel);
            app.SecondsDropDown.Items = {'3', '4', '5', '7'};
            app.SecondsDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondsDropDownValueChanged, true);
            app.SecondsDropDown.Value = '3';
            app.SampleRateDropDownLabel = uilabel(app.RecordAudioPanel);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.SampleRateDropDown = uidropdown(app.RecordAudioPanel);
            app.SampleRateDropDown.Items = {'8000', '11025', '44100', '48000'};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Value = '8000';
            app.ImportAudiofromDiskPanel = uipanel(app.UIFigure);
            app.ImportAudiofromDiskPanel.Title = 'Import Audio from Disk';
            app.ImportAudioFileButton = uibutton(app.ImportAudiofromDiskPanel, 'push');
            app.ImportAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioFileButtonPushed, true);
            app.ImportAudioFileButton.Text = 'Import Audio File';
            app.SelectedAudioEditFieldLabel = uilabel(app.ImportAudiofromDiskPanel);
            app.SelectedAudioEditFieldLabel.Text = 'Selected Audio';
            app.SelectedAudioEditField = uieditfield(app.ImportAudiofromDiskPanel, 'text');
            app.SelectedAudioEditField.Editable = 'off';
            app.PlayoriginalaudiofileButton = uibutton(app.ImportAudiofromDiskPanel, 'push');
            app.PlayoriginalaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalaudiofileButtonPushed, true);
            app.PlayoriginalaudiofileButton.Text = 'Play original audio file';
            app.SynthesizeVoicePanel = uipanel(app.UIFigure);
            app.SynthesizeVoicePanel.Title = 'Synthesize Voice';
            app.SyntesizeVoiceButton = uibutton(app.SynthesizeVoicePanel, 'push');
            app.SyntesizeVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @SyntesizeVoiceButtonPushed, true);
            app.SyntesizeVoiceButton.Text = 'Syntesize Voice';
            app.PlaysynthesizedvoiceButton = uibutton(app.SynthesizeVoicePanel, 'push');
            app.PlaysynthesizedvoiceButton.ButtonPushedFcn = createCallbackFcn(app, @PlaysynthesizedvoiceButtonPushed, true);
            app.PlaysynthesizedvoiceButton.Text = 'Play synthesized voice';
            app.PlayoriginalvoiceButton = uibutton(app.SynthesizeVoicePanel, 'push');
            app.PlayoriginalvoiceButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalvoiceButtonPushed, true);
            app.PlayoriginalvoiceButton.Text = 'Play original voice';
            app.Lamp_3 = uilamp(app.SynthesizeVoicePanel);
            app.Lamp_4 = uilamp(app.SynthesizeVoicePanel);
            app.TextArea = uitextarea(app.SynthesizeVoicePanel);
            app.TextArea.Editable = 'off';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Synthesized Voice Spectrogram')
            xlabel(app.UIAxes4, 'time [s]')
            ylabel(app.UIAxes4, 'frequency [Hz]')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Synthesized Voice Time Plot')
            xlabel(app.UIAxes3, 'time [s]')
            ylabel(app.UIAxes3, 'amplitude')
            app.LPC10VOCODERLabel = uilabel(app.UIFigure);
            app.LPC10VOCODERLabel.Text = 'LPC-10 VOCODER';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEC201_proj_APP_v5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end